classdef FitnessTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FrontPageTab                  matlab.ui.container.Tab
        GetStartedButton              matlab.ui.control.Button
        CreatedbyMathWorksInternshipGroup17Label  matlab.ui.control.Label
        FitnessTrackerLabel_3         matlab.ui.control.Label
        DailyCalorieEstimatorTab      matlab.ui.container.Tab
        NextButton                    matlab.ui.control.Button
        FitnessTrackerLabel_2         matlab.ui.control.Label
        TotalCaloriesBurnedEditField  matlab.ui.control.NumericEditField
        ActivityLevelDropDown         matlab.ui.control.DropDown
        ActivityLevelDropDownLabel    matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        GenderDropDownLabel           matlab.ui.control.Label
        AgeyearsEditField             matlab.ui.control.NumericEditField
        AgeyearsEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField             matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel        matlab.ui.control.Label
        WeightkgEditField             matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel        matlab.ui.control.Label
        TotalCaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.StateButton
        PleaseenterthefollowinginformationLabel  matlab.ui.control.Label
        FoodTrackerTab                matlab.ui.container.Tab
        TextArea_5                    matlab.ui.control.TextArea
        TextArea_5Label               matlab.ui.control.Label
        TextArea_4                    matlab.ui.control.TextArea
        TextArea_4Label               matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        ProteinEditField              matlab.ui.control.NumericEditField
        ProteinEditFieldLabel         matlab.ui.control.Label
        NameofFoodEditField           matlab.ui.control.EditField
        NameofFoodEditFieldLabel      matlab.ui.control.Label
        TotalCaloriesEditField        matlab.ui.control.NumericEditField
        TotalCaloriesEditFieldLabel   matlab.ui.control.Label
        CaloriesofFoodEditField       matlab.ui.control.NumericEditField
        CaloriesofFoodEditFieldLabel  matlab.ui.control.Label
        FatEditField                  matlab.ui.control.NumericEditField
        FatEditFieldLabel             matlab.ui.control.Label
        CarbohydratesEditField        matlab.ui.control.NumericEditField
        CarbohydratesEditFieldLabel   matlab.ui.control.Label
        PleaseenterthefollowinginformationLabel_2  matlab.ui.control.Label
        CalculateButton_2             matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        HealthReportTab               matlab.ui.container.Tab
        UserReportLabel               matlab.ui.control.Label
        TextArea_8                    matlab.ui.control.TextArea
        TextArea_8Label               matlab.ui.control.Label
        TextArea_7                    matlab.ui.control.TextArea
        TextArea_7Label               matlab.ui.control.Label
        TextArea_6                    matlab.ui.control.TextArea
        TextArea_6Label               matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        totalProtein = 0;
        totalCarbs = 0;
        totalFat = 0;
        totalCalories = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % pie(app.UIAxes, [1, 1, 1])
        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            %Assign variables and store them into struct
            userData.weight = app.WeightkgEditField.Value;
            userData.height = app.HeightcmEditField.Value;
            userData.age = app.AgeyearsEditField.Value;
            userData.gender = app.GenderDropDown.Value;
            userData.activityLvl = app.ActivityLevelDropDown.Value;
            userData.totalCaloriesEaten = 0;

            %Call function to calculate total calories burned
            app.TotalCaloriesBurnedEditField.Value = calculateTDEE(userData);

            %Save value to struct
            userData.TDEE = app.TotalCaloriesBurnedEditField.Value;

            %Calculate Caloric Deficit and Surplus 
            minimumCal = userData.TDEE * 0.10; %minimum threshold to be in surplus/deficit
            userData.caloricDeficit = userData.TDEE - minimumCal;
            userData.caloricSurplus = userData.TDEE + minimumCal;
            
            %Update the plot for Recommended Caloric Graph
            labels = categorical({'Deficit','Maintanence','Surplus','Current'});
            labels = reordercats(labels,{'Deficit','Maintanence','Surplus','Current'});
            caloricStates = [userData.caloricDeficit userData.TDEE userData.caloricSurplus 1]; 
            bar(app.UIAxes2, labels, caloricStates);
            title(app.UIAxes2, 'Caloric Graph');

            %Save struct as file to use later
            save('userData.mat', 'userData');
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            load("userData.mat","userData")
            %Assign variables and store them in food struct
            foodData.name = app.NameofFoodEditField.Value;
            foodData.protein = app.ProteinEditField.Value;
            foodData.fat = app.FatEditField.Value;
            foodData.carbohydrates = app.CarbohydratesEditField.Value;
            
            %Update total calories eaten
            userData.totalCaloriesEaten = userData.totalCaloriesEaten + calcMacros(foodData);

            %Calculate calories of food and total calories for person
            app.CaloriesofFoodEditField.Value = calcMacros(foodData);
            app.TotalCaloriesEditField.Value = userData.totalCaloriesEaten;
            %app.Slider.Value = userData.totalCaloriesEaten; removed it
            
            %% Update Macronutrients Graph
            % Add current food's macros to cumulative totals
            app.totalProtein = app.totalProtein + foodData.protein;
            app.totalCarbs   = app.totalCarbs   + foodData.carbohydrates;
            app.totalFat     = app.totalFat     + foodData.fat;
            
            % Create matrix of cumulative macros
            macroGrams = [app.totalProtein, app.totalCarbs, app.totalFat];
            
            % Update pie chart
            cla(app.UIAxes);
            pie(app.UIAxes, macroGrams);
            title(app.UIAxes, 'Actual Macronutrients');
            
            %% Update Caloric Graph
            labels = categorical({'Deficit','Maintanence','Surplus','Current'});
            labels = reordercats(labels,{'Deficit','Maintanence','Surplus','Current'});

            cla(app.UIAxes2);
            caloricStates = [userData.caloricDeficit userData.TDEE userData.caloricSurplus userData.totalCaloriesEaten];
            bar(app.UIAxes2, labels, caloricStates)
            title(app.UIAxes2, 'Caloric Graph');

            %% Save foods and total calories for the day
            save("foodData.mat", "foodData");
            save("userData.mat","userData");
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DailyCalorieEstimatorTab;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FoodTrackerTab;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FrontPageTab = uitab(app.TabGroup);
            app.FrontPageTab.Title = 'Front Page';
            app.FitnessTrackerLabel_3 = uilabel(app.FrontPageTab);
            app.FitnessTrackerLabel_3.Text = 'Fitness Tracker';
            app.CreatedbyMathWorksInternshipGroup17Label = uilabel(app.FrontPageTab);
            app.CreatedbyMathWorksInternshipGroup17Label.Text = 'Created by MathWorks Internship Group 17';
            app.GetStartedButton = uibutton(app.FrontPageTab, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.DailyCalorieEstimatorTab = uitab(app.TabGroup);
            app.DailyCalorieEstimatorTab.Title = 'Daily Calorie Estimator';
            app.PleaseenterthefollowinginformationLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.PleaseenterthefollowinginformationLabel.Text = 'Please enter the following information';
            app.CalculateButton = uibutton(app.DailyCalorieEstimatorTab, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.TotalCaloriesBurnedEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.TotalCaloriesBurnedEditFieldLabel.Text = 'Total Calories Burned';
            app.WeightkgEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.HeightcmEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.AgeyearsEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.AgeyearsEditFieldLabel.Text = 'Age (years)';
            app.AgeyearsEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.GenderDropDownLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.DailyCalorieEstimatorTab);
            app.GenderDropDown.Items = {'Male ', 'Female'};
            app.GenderDropDown.Value = 'Male ';
            app.ActivityLevelDropDownLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.ActivityLevelDropDownLabel.Text = 'Activity Level';
            app.ActivityLevelDropDown = uidropdown(app.DailyCalorieEstimatorTab);
            app.ActivityLevelDropDown.Items = {'Sedentary (little to no exercise)', 'Lightly active (light exercise 1–3 days per week)', 'Moderately active (moderate exercise 3–5 days per week)', 'Very active (hard exercise 6–7 days per week)', 'Extra active (very hard exercise two or more times per day, training, or a physical job)'};
            app.ActivityLevelDropDown.Value = 'Sedentary (little to no exercise)';
            app.TotalCaloriesBurnedEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.TotalCaloriesBurnedEditField.Editable = 'off';
            app.FitnessTrackerLabel_2 = uilabel(app.DailyCalorieEstimatorTab);
            app.FitnessTrackerLabel_2.Text = 'Fitness Tracker';
            app.NextButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FoodTrackerTab = uitab(app.TabGroup);
            app.FoodTrackerTab.Title = 'Food Tracker';
            app.UIAxes = uiaxes(app.FoodTrackerTab);
            title(app.UIAxes, 'Actual Macronutrients')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.FoodTrackerTab);
            title(app.UIAxes2, 'Calorie Comparision')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CalculateButton_2 = uibutton(app.FoodTrackerTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.PleaseenterthefollowinginformationLabel_2 = uilabel(app.FoodTrackerTab);
            app.PleaseenterthefollowinginformationLabel_2.Text = 'Please enter the following information';
            app.CarbohydratesEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.CarbohydratesEditFieldLabel.Text = 'Carbohydrates';
            app.CarbohydratesEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.CarbohydratesEditField.AllowEmpty = 'on';
            app.CarbohydratesEditField.Value = [];
            app.FatEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.FatEditFieldLabel.Text = 'Fat';
            app.FatEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.FatEditField.AllowEmpty = 'on';
            app.FatEditField.Value = [];
            app.CaloriesofFoodEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.CaloriesofFoodEditFieldLabel.Text = 'Calories of Food';
            app.CaloriesofFoodEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.CaloriesofFoodEditField.AllowEmpty = 'on';
            app.CaloriesofFoodEditField.Editable = 'off';
            app.CaloriesofFoodEditField.Value = [];
            app.TotalCaloriesEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.TotalCaloriesEditFieldLabel.Text = 'Total Calories';
            app.TotalCaloriesEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.TotalCaloriesEditField.AllowEmpty = 'on';
            app.TotalCaloriesEditField.Editable = 'off';
            app.TotalCaloriesEditField.Value = [];
            app.NameofFoodEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.NameofFoodEditFieldLabel.Text = 'Name of Food';
            app.NameofFoodEditField = uieditfield(app.FoodTrackerTab, 'text');
            app.ProteinEditFieldLabel = uilabel(app.FoodTrackerTab);
            app.ProteinEditFieldLabel.Text = 'Protein';
            app.ProteinEditField = uieditfield(app.FoodTrackerTab, 'numeric');
            app.ProteinEditField.AllowEmpty = 'on';
            app.ProteinEditField.Value = [];
            app.Label = uilabel(app.FoodTrackerTab);
            app.Label.Text = '.';
            app.TextArea = uitextarea(app.FoodTrackerTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Protein'};
            app.TextArea_4Label = uilabel(app.FoodTrackerTab);
            app.TextArea_4Label.Text = '.';
            app.TextArea_4 = uitextarea(app.FoodTrackerTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Carbs'};
            app.TextArea_5Label = uilabel(app.FoodTrackerTab);
            app.TextArea_5Label.Text = '.';
            app.TextArea_5 = uitextarea(app.FoodTrackerTab);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Fats'};
            app.HealthReportTab = uitab(app.TabGroup);
            app.HealthReportTab.Title = 'Health Report';
            app.UIAxes_2 = uiaxes(app.HealthReportTab);
            title(app.UIAxes_2, 'Actual Macronutrients')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TextArea_6Label = uilabel(app.HealthReportTab);
            app.TextArea_6Label.Text = '.';
            app.TextArea_6 = uitextarea(app.HealthReportTab);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Protein'};
            app.TextArea_7Label = uilabel(app.HealthReportTab);
            app.TextArea_7Label.Text = '.';
            app.TextArea_7 = uitextarea(app.HealthReportTab);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Carbs'};
            app.TextArea_8Label = uilabel(app.HealthReportTab);
            app.TextArea_8Label.Text = '.';
            app.TextArea_8 = uitextarea(app.HealthReportTab);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'Carbs'};
            app.UserReportLabel = uilabel(app.HealthReportTab);
            app.UserReportLabel.Text = 'User Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitnessTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end